classdef noisuy < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        XInputField_2       matlab.ui.control.EditField
        NhpxaLabel_2        matlab.ui.control.Label
        YInputField         matlab.ui.control.EditField
        NhpyaLabel          matlab.ui.control.Label
        XInputField         matlab.ui.control.EditField
        NhpxaLabel          matlab.ui.control.Label
        ResultTextArea      matlab.ui.control.TextArea
        KtquathcnisuyLabel  matlab.ui.control.Label
        KtqunisuyButton     matlab.ui.control.Button
        MethodDropdown      matlab.ui.control.DropDown
        ChnphngphpLabel     matlab.ui.control.Label
        UIAxes              matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: KtqunisuyButton
        function KtqunisuyButtonPushed(app, event)
          
   % 1. Đọc và xử lý dãy xa
xaInput = strrep(app.XInputField.Value, ',', ' '); % Thay dấu phẩy bằng dấu cách
xa = str2num(xaInput);

% 2. Đọc và xử lý dãy ya
yaInput = strrep(app.YInputField.Value, ',', ' '); % Thay dấu phẩy bằng dấu cách
ya = str2num(yaInput);

% 3. Đọc và xử lý dãy x cần nội suy
xInput = strrep(app.XInputField_2.Value, ',', ' ');
x = str2num(xInput);

% 4. Kiểm tra tính hợp lệ của dữ liệu
if isempty(xa) || isempty(ya)
    uialert(app.UIFigure, 'Dữ liệu x hoặc y không hợp lệ. Vui lòng nhập lại!', 'Lỗi');
    return;
end
if length(xa) ~= length(ya)
    uialert(app.UIFigure, 'Dữ liệu x và y phải có cùng số lượng phần tử!', 'Lỗi');
    return;
end
if isempty(x)
    uialert(app.UIFigure, 'Dữ liệu x cần nội suy không hợp lệ. Vui lòng nhập lại!', 'Lỗi');
    return;
end

% 5. Đọc phương pháp nội suy được chọn
selectedMethod = app.MethodDropdown.Value;

% 6. Thực hiện nội suy
try
    results = zeros(size(x));  % Tạo mảng chứa kết quả nội suy
    for i = 1:length(x)
        switch selectedMethod
            case 'Newton'
                results(i) = NewtonInterpolation2(xa, ya, x(i));
            case 'Lagrange'
                results(i) = LagrangeInterpolation2(xa, ya, x(i));
            case 'Spline'
                results(i) = SplineInterpolation(xa, ya, x(i));
            otherwise
                uialert(app.UIFigure, 'Phương pháp nội suy không hợp lệ!', 'Lỗi');
                return;
        end
    end
catch
    uialert(app.UIFigure, 'Có lỗi xảy ra trong quá trình nội suy!', 'Lỗi');
    return;
end

% 7. Hiển thị kết quả
resultStr = join(arrayfun(@(r) sprintf('%.4f', r), results, 'UniformOutput', false), ', ');
app.ResultTextArea.Value = ['Kết quả nội suy: ', resultStr];

% 8. Vẽ đồ thị
plot(app.UIAxes, xa, ya, 'o-', 'LineWidth', 1.5);  % Vẽ các điểm dữ liệu
hold(app.UIAxes, 'on');
scatter(app.UIAxes, x, results, 'r', 'filled');  % Vẽ các điểm nội suy
hold(app.UIAxes, 'off');


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Đồ thị nội suy')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ChnphngphpLabel = uilabel(app.UIFigure);
            app.ChnphngphpLabel.Text = 'Chọn phương pháp';
            app.MethodDropdown = uidropdown(app.UIFigure);
            app.MethodDropdown.Items = {'Newton', 'Lagrange', 'Spline'};
            app.MethodDropdown.Value = 'Newton';
            app.KtqunisuyButton = uibutton(app.UIFigure, 'push');
            app.KtqunisuyButton.ButtonPushedFcn = createCallbackFcn(app, @KtqunisuyButtonPushed, true);
            app.KtqunisuyButton.Text = 'Kết quả nội suy';
            app.KtquathcnisuyLabel = uilabel(app.UIFigure);
            app.KtquathcnisuyLabel.Text = 'Kết quả đa thức nội suy';
            app.ResultTextArea = uitextarea(app.UIFigure);
            app.NhpxaLabel = uilabel(app.UIFigure);
            app.NhpxaLabel.Text = 'Nhập xa:';
            app.XInputField = uieditfield(app.UIFigure, 'text');
            app.NhpyaLabel = uilabel(app.UIFigure);
            app.NhpyaLabel.Text = 'Nhập ya:';
            app.YInputField = uieditfield(app.UIFigure, 'text');
            app.NhpxaLabel_2 = uilabel(app.UIFigure);
            app.NhpxaLabel_2.Text = 'Nhập x:';
            app.XInputField_2 = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = noisuy

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end